classdef HOA_PD_SRM_HandFit_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotNewReconstructionButton    matlab.ui.control.Button
        PlotOriginalReconstructionButton  matlab.ui.control.Button
        LoadButton                     matlab.ui.control.Button
        PerturbationMagnitudeDropDown  matlab.ui.control.DropDown
        PerturbationMagnitudeDropDownLabel  matlab.ui.control.Label
        PerturbationDirectionDropDown  matlab.ui.control.DropDown
        PerturbationDirectionDropDownLabel  matlab.ui.control.Label
        ReconstructionTypeDropDown     matlab.ui.control.DropDown
        ReconstructionTypeDropDownLabel  matlab.ui.control.Label
        ParticipantDropDown            matlab.ui.control.DropDown
        ParticipantDropDownLabel       matlab.ui.control.Label
        Slider_2                       matlab.ui.control.Slider
        Slider_2Label                  matlab.ui.control.Label
        k_d2Slider                     matlab.ui.control.Slider
        k_d2SliderLabel                matlab.ui.control.Label
        k_v2Slider                     matlab.ui.control.Slider
        k_v2SliderLabel                matlab.ui.control.Label
        k_a2Slider                     matlab.ui.control.Slider
        k_a2SliderLabel                matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        k_d1Slider                     matlab.ui.control.Slider
        k_d1SliderLabel                matlab.ui.control.Label
        k_v1Slider                     matlab.ui.control.Slider
        k_v1SliderLabel                matlab.ui.control.Label
        k_a1Slider                     matlab.ui.control.Slider
        k_a1SliderLabel                matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data
        dataAv
        Old_Recon
        Old_Gains_global
        New_Gains
        New_fit
        New_Recon
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function loadData(app, event)
            % Allow the user to select a file
            [file, path, ~] = uigetfile({'*.mat', 'MATLAB Files (*.mat)'; ...
                '*.*', 'All Files (*.*)'}, 'Select Data File');

            % Check if the user canceled the operation
            if isequal(file,0)
                disp('User canceled the operation');
                return;
            end

            % Load the data from the selected file
            fileName = [path file];
            app.data = load(fileName);
            app.dataAv = app.data.dataAv;
               
        end

        % Drop down opening function: ParticipantDropDown
        function choose_participant(app, event)
            % Define your array of options
            participantArray = unique(app.dataAv.patient);

            % Set the drop-down options
            app.ParticipantDropDown.Items = participantArray;
        end

        % Drop down opening function: PerturbationDirectionDropDown
        function choose_direction(app, event)
            % Define your array of options
            directionArray = unique(app.dataAv.pertdir_calc_round_deg);

            % Set the drop-down options
            app.PerturbationDirectionDropDown.Items = string(directionArray);
        end

        % Drop down opening function: PerturbationMagnitudeDropDown
        function choose_magnitude(app, event)
            % Define your array of options
            magnitudeArray = unique(app.dataAv.condition);

            % Set the drop-down options
            app.PerturbationMagnitudeDropDown.Items = string(magnitudeArray);
        end

        % Button pushed function: PlotOriginalReconstructionButton
        function plot_originalRecon(app, event)
            cla(app.UIAxes)   
            clf(app.UIAxes)
            cla(app.UIAxes2)   
            clf(app.UIAxes2)
            
            participant = app.ParticipantDropDown.Value;
            direction = app.PerturbationDirectionDropDown.Value;
            direction = str2num(direction);
            mag = app.PerturbationMagnitudeDropDown.Value;
            mag = str2num(mag);
            ReconName = app.ReconstructionTypeDropDown.Value;
            addpath('matlabUtilities-master', 'SRMUtilities');

            %% create index and define plotting limits
            ind_row = find(strcmp(app.dataAv.patient,participant) & app.dataAv.pertdir_calc_round_deg == direction & app.dataAv.condition == mag); % participant/condition index
            xl = [-0.2 1.6];
            yl = [-0.6 1.2];

            %% specify CoM kinematics and time window
            atime = app.dataAv.atime(ind_row,:); % Modify CoM acc with stiction model from Welch and Ting 2009
            a = app.dataAv.COMAccel_Y(ind_row,:);
            a = stictionTemplate('a',a,'t',atime);
            v = app.dataAv.COMVelo_Y(ind_row,:);
            d = app.dataAv.COMPosminusLVDT_Y(ind_row,:);
            % remove basline from CoM kinematics - not needed for "a" due to stiction model
            d = d-mean(d(app.dataAv.atime(1,:)<-0.1));
            v = v-mean(v(app.dataAv.atime(1,:)<-0.1));
            % specify time fit by SRM - FROM HOA_PD_SRM.m
            max_time = 1.2;
            min_time = -0.2;
            ind_time = find(app.dataAv.atime(1,:) > min_time & app.dataAv.atime(1,:) <= max_time); % adjust window that will be fit by the SRM
            
            %% flip the CoM kinematic signal depending on pert direction and specify ag/antag EMG
            if direction == 90 %forward pert
                a_ag = -a; v_ag = -v; d_ag = -d;
                a_antag = a; v_antag = v; d_antag = d;
                agonist = app.dataAv.EMG_TA_L_norm(ind_row,:); tag_ag = 'TA'; %ag_norm = dataAv.EMG_TA_L_norm(ind_row,:);
                antagonist = app.dataAv.EMG_MGAS_L_norm(ind_row,:); tag_antag = 'MG'; antag_norm = app.dataAv.EMG_MGAS_L_norm(ind_row,:);
            elseif direction == 270 %backward pert
                a_ag = a; v_ag = v; d_ag = d;
                a_antag = -a; v_antag = -v; d_antag = -d;
                antagonist = app.dataAv.EMG_TA_L_norm(ind_row,:); tag_antag = 'TA'; antag_norm = app.dataAv.EMG_TA_L_norm(ind_row,:);
                agonist = app.dataAv.EMG_MGAS_L_norm(ind_row,:); tag_ag = 'MG'; %ag_norm = dataAv.EMG_MGAS_L_norm(ind_row,:);
            end

            %% specify EEG data
            Cz = app.dataAv.Cz_norm(ind_row,:);
            beta = app.dataAv.beta_ersp_norm(ind_row,:);
  
            %% Specify appropriate neurophysiological data, SRM recon, and gains

            UB1 = [15.0, 0.04, 0.07, 0.300];
            LB1 = [0.0, 0.00, 0.00, 0.000];
            lambda2bounds =[0.1, 0.4];

            if strcmp(ReconName,"Recon_Agonist")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
                measuredData = agonist(ind_time); % what the SRM is reconstructing
                app.Old_Recon = app.dataAv.Recon_Agonist(ind_row,:); %SRM reconstruction time series
                Old_Gains = app.dataAv.Gains_Ag(ind_row,:); %SRM reconstruction gains
                Old_fit = app.dataAv.fit_agonist(ind_row,:); %SRM reconstruction accuracy

                app.New_Gains = Old_Gains;

                app.k_a1Slider.Value = Old_Gains(1);
                app.k_v1Slider.Value = Old_Gains(2);
                app.k_d1Slider.Value = Old_Gains(3);
                app.Slider.Value = Old_Gains(4);
                app.k_a1Slider.Limits = [LB1(1), UB1(1)];
                app.k_v1Slider.Limits = [LB1(2), UB1(2)];
                app.k_d1Slider.Limits = [LB1(3), UB1(3)];
                app.Slider.Limits = [LB1(4), UB1(4)];

                app.k_a2Slider.Enable = 'off';
                app.k_v2Slider.Enable = 'off';
                app.k_d2Slider.Enable = 'off';
                app.Slider_2.Enable = 'off';
            
%                 New_Recon = assembleChannel(predictors,New_Gains(1:3),New_Gains(end),atime(ind_time));
            
            elseif strcmp(ReconName,"Recon_Agonist_TotalDual_CoM")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time);...
                    a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
                measuredData = agonist(ind_time); % what the SRM is reconstructing
                app.Old_Recon = app.dataAv.Recon_Agonist_TotalDual_CoM(ind_row,:); %SRM reconstruction time series
                Old_Gains = app.dataAv.Gains_Ag_TotalDual_CoM(ind_row,:); %SRM reconstruction gains
                Old_fit = app.dataAv.fit_agonist_TotalDual_CoM(ind_row,:); %SRM reconstruction accuracy
            
                app.New_Gains = Old_Gains;

                app.k_a1Slider.Value = Old_Gains(1);
                app.k_v1Slider.Value = Old_Gains(2);
                app.k_d1Slider.Value = Old_Gains(3);
                app.Slider.Value = Old_Gains(4);
                app.k_a1Slider.Limits = [LB1(1), UB1(1)];
                app.k_v1Slider.Limits = [LB1(2), UB1(2)];
                app.k_d1Slider.Limits = [LB1(3), UB1(3)];
                app.Slider.Limits = [LB1(4), UB1(4)];

                app.k_a2Slider.Value = Old_Gains(5);
                app.k_v2Slider.Value = Old_Gains(6);
                app.k_d2Slider.Value = Old_Gains(7);
                app.Slider_2.Value = Old_Gains(8);
                app.k_a2Slider.Limits = [LB1(1), UB1(1)];
                app.k_v2Slider.Limits = [LB1(2), UB1(2)];
                app.k_d2Slider.Limits = [LB1(3), UB1(3)];
                app.Slider_2.Limits = [lambda2bounds(1), lambda2bounds(2)];

                app.k_a2Slider.Enable = 'on';
                app.k_v2Slider.Enable = 'on';
                app.k_d2Slider.Enable = 'on';
                app.Slider_2.Enable = 'on';

%                 New_Recon = assembleTwoChannels(predictors(1:3,:),New_Gains(1:3),New_Gains(4),...
%                     predictors(4:6,:),New_Gains(5:7),New_Gains(end),atime(ind_time));
            
%             elseif strcmp(ReconName,"Recon_Agonist_TotalDual_Cz")
%                 predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time); -Cz(ind_time)]; % SRM predictors
%                 measuredData = agonist(ind_time); % what the SRM is reconstructing
%                 Old_Recon = app.dataAv.Recon_Agonist_TotalDual_Cz(ind_row,:); %SRM reconstruction time series
%                 Old_Gains = app.dataAv.Gains_Ag_TotalDual_Cz(ind_row,:); %SRM reconstruction gains
%                 Old_fit = app.dataAv.fit_agonist_TotalDual_Cz(ind_row,:); %SRM reconstruction accuracy
%             
% %                 New_Recon = assembleTwoChannels(predictors(1:3,:),New_Gains(1:3),New_Gains(4),...
% %                     predictors(4,:),New_Gains(5),New_Gains(end),atime(ind_time));
% %             
%             elseif strcmp(ReconName,"Recon_Agonist_TotalDual_beta")
%                 predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time); beta(ind_time)]; % SRM predictors
%                 measuredData = agonist(ind_time); % what the SRM is reconstructing
%                 Old_Recon = app.dataAv.Recon_Agonist_TotalDual_beta(ind_row,:); %SRM reconstruction time series
%                 Old_Gains = app.dataAv.Gains_Ag_TotalDual_beta(ind_row,:); %SRM reconstruction gains
%                 Old_fit = app.dataAv.fit_agonist_TotalDual_beta(ind_row,:); %SRM reconstruction accuracy
%             
% %                 New_Recon = assembleTwoChannels(predictors(1:3,:),New_Gains(1:3),New_Gains(4),...
% %                     predictors(4,:),New_Gains(5),New_Gains(end),atime(ind_time));
%             
            elseif strcmp(ReconName,"Recon_Cz")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
                measuredData = -Cz(ind_time); % what the SRM is reconstructing
                app.Old_Recon = -app.dataAv.Recon_Cz(ind_row,:); %SRM reconstruction time series
                Old_Gains = app.dataAv.Gains_Cz(ind_row,:); %SRM reconstruction gains
                Old_fit = app.dataAv.fit_Cz(ind_row,:); %SRM reconstruction accuracy

                app.New_Gains = Old_Gains;

                app.k_a1Slider.Limits = [LB1(1), UB1(1)];
                app.k_v1Slider.Limits = [LB1(2), UB1(2)];
                app.k_d1Slider.Limits = [LB1(3), UB1(3)];
                app.Slider.Limits = [LB1(4), UB1(4)];
                app.k_a1Slider.Value = Old_Gains(1);
                app.k_v1Slider.Value = Old_Gains(2);
                app.k_d1Slider.Value = Old_Gains(3);
                app.Slider.Value = Old_Gains(4);

                app.k_a2Slider.Enable = 'off';
                app.k_v2Slider.Enable = 'off';
                app.k_d2Slider.Enable = 'off';
                app.Slider_2.Enable = 'off';
%             
%                 New_Recon = assembleChannel(predictors,New_Gains(1:3),New_Gains(end),atime(ind_time));
% %             
%             elseif strcmp(ReconName,"Recon_beta")
%                 predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
%                 measuredData = beta(ind_time); % what the SRM is reconstructing
%                 Old_Recon = app.dataAv.Recon_Beta(ind_row,:); %SRM reconstruction time series
%                 Old_Gains = app.dataAv.Gains_Beta(ind_row,:); %SRM reconstruction gains
%                 Old_fit = app.dataAv.fit_beta(ind_row,:); %SRM reconstruction accuracy
%             
% %                 New_Recon = assembleChannel(predictors,New_Gains(1:3),New_Gains(end),atime(ind_time));
%             
%             elseif strcmp(ReconName,"Recon_Antagonist")
%                 predictors = [a_antag(ind_time); v_antag(ind_time); d_antag(ind_time);....
%                     -a_antag(ind_time); -v_antag(ind_time); -d_antag(ind_time)]; % SRM predictors
%                 measuredData = antagonist(ind_time); % what the SRM is reconstructing
%                 Old_Recon = app.dataAv.Recon_Antagonist(ind_row,:); %SRM reconstruction time series
%                 Old_Gains = app.dataAv.Gains_Antag(ind_row,:); %SRM reconstruction gains
%                 Old_fit = app.dataAv.fit_antagonist(ind_row,:); %SRM reconstruction accuracy
%             
% %                 New_Recon = assembleTwoChannels(predictors(1:3,:),New_Gains(1:3),New_Gains(4),...
% %                     predictors(4:6,:),New_Gains(5:7),New_Gains(end),atime(ind_time));
            
            else
                error('Specified SRMType is not in dataAv')
            end

            %% Calculate R2 and VAF for new fit
%             New_fit = [NaN NaN];
%             New_fit(1) = rsqr(measuredData',New_Recon');
%             New_fit(2) = rsqr_uncentered(measuredData',New_Recon');
%             output = table(participant, direction, mag, ReconName, New_Gains, New_fit, New_Recon, Old_Gains, Old_Recon);
            
            %% plot Old SRM Recons
            backLev = mean(measuredData(atime(ind_time) < -0.1),'omitnan');
%             title_string = {participant + " Mag: " + string(mag) + " direc: " + string(direction) + " " + ReconName};
            
            figure('visible','off'); 
            set(gcf,'WindowStyle','docked')
            colors = [
                0.8500 0.3250 0.0980; % Orange
                0.9290 0.6940 0.1250; % Yellow
                0.4940 0.1840 0.5560; % Purple
                0.4660 0.6740 0.1880; % Green
                0.3010 0.7450 0.9330; % Light Blue
                1 0.5 0.6;            % Pink
            ];
            
            %% Old Recon
            plotij(2,1,1,1); hold(app.UIAxes,'on')
            plot(app.UIAxes, atime(ind_time),measuredData,'Color', colors(5,:),'LineWidth',2) % raw data
            hold(app.UIAxes,'on')
            plot(app.UIAxes, atime(ind_time),app.Old_Recon,'Color', colors(6,:),'LineWidth',2) % Reconstruction
            hold(app.UIAxes,'on')

            if strcmp(ReconName,"Recon_Agonist") || strcmp(ReconName,"Recon_beta") || strcmp(ReconName,"Recon_Cz") % for SRMs w/ only 3 predictors & 1 delay
                plot(app.UIAxes, atime(ind_time) + Old_Gains(4),...
                    predictors(1,:)*Old_Gains(1)+backLev,...
                    'Color', colors(4,:),'Linestyle', '-','LineWidth',1.2) % CoM Acc Component
                hold(app.UIAxes,'on')
                plot(app.UIAxes, atime(ind_time)+Old_Gains(4),...
                    predictors(2,:)*Old_Gains(2)+backLev,...
                    'Color', colors(4,:),'Linestyle', ':','LineWidth',0.5) % CoM Vel Component
                hold(app.UIAxes,'on')
                plot(app.UIAxes, atime(ind_time)+Old_Gains(4),...
                    predictors(3,:)*Old_Gains(3)+backLev,...
                    'Color', colors(4,:),'Linestyle', '--','LineWidth',0.5) % CoM Disp component
                hold(app.UIAxes,'on')
                h = area(app.UIAxes, atime(ind_time),app.Old_Recon, 'EdgeColor', colors(6,:));
                h.FaceColor = [1 0.5 0.6]; % Set the shading color (RGB triplet)
                h.FaceAlpha = 0.1; % Set the transparency (value between 0 and 1)
                legend(app.UIAxes,'Data',ReconName,...
                    sprintf('k_{a1}=%1.2g',Old_Gains(1)), sprintf('k_{v1}=%1.2g',Old_Gains(2)), sprintf('k_{d1}=%1.2g',Old_Gains(3)),'Interpreter','none');
                if strcmp(ReconName,"Recon_Cz")
                    ylabel(app.UIAxes, 'Cz'); xlabel(app.UIAxes, 'Time (s)');
                elseif strcmp(ReconName,"Recon_Agonist")
                    ylabel(app.UIAxes, [tag_ag ' EMG']); xlabel(app.UIAxes, 'Time (s)');
                end

            elseif strcmp(ReconName,"Recon_Agonist_TotalDual_CoM")  || strcmp(ReconName,"Recon_Antagonist") % for SRMs w/ 6 predictors & 2 delays
                plot(app.UIAxes, atime(ind_time) + Old_Gains(4),...
                    predictors(1,:)*Old_Gains(1)+backLev,...
                    'Color', colors(4,:), 'Linestyle', '-','LineWidth',1.2) % CoM Acc Component
                hold(app.UIAxes,'on')
                plot(app.UIAxes, atime(ind_time)+Old_Gains(4),...
                    predictors(2,:)*Old_Gains(2)+backLev,...
                    'Color', colors(4,:), 'Linestyle', ':','LineWidth',0.5) % CoM Vel Component
                hold(app.UIAxes,'on')
                plot(app.UIAxes, atime(ind_time)+Old_Gains(4),...
                    predictors(3,:)*Old_Gains(3)+backLev,...
                    'Color', colors(4,:), 'Linestyle', '--','LineWidth',0.5) % CoM Disp component
                hold(app.UIAxes,'on')
                plot(app.UIAxes, atime(ind_time)+Old_Gains(end),...
                    predictors(4,:)*Old_Gains(5)+backLev,...
                    'Color', colors(2,:), 'Linestyle', '-','LineWidth',1.2) % CoM Acc - ctx
                hold(app.UIAxes,'on')
                plot(app.UIAxes, atime(ind_time)+Old_Gains(end),...
                    predictors(5,:)*Old_Gains(6)+backLev,...
                    'Color', colors(2,:), 'Linestyle', ':','LineWidth',0.5) % CoM Vel - ctx
                hold(app.UIAxes,'on')
                plot(app.UIAxes, atime(ind_time)+Old_Gains(end),...
                    predictors(6,:)*Old_Gains(7)+backLev,...
                    'Color', colors(2,:), 'Linestyle', '--','LineWidth',0.5)% CoM Disp - ctx
                hold(app.UIAxes,'on')
                h = area(app.UIAxes, atime(ind_time),app.Old_Recon, 'EdgeColor', colors(6,:));
                h.FaceColor = [1 0.5 0.6]; % Set the shading color (RGB triplet)
                h.FaceAlpha = 0.1; % Set the transparency (value between 0 and 1)
                legend(app.UIAxes,'Data',ReconName,...
                    sprintf('k_{a1}=%1.2g',Old_Gains(1)), sprintf('k_{v1}=%1.2g',Old_Gains(2)), sprintf('k_{d1}=%1.2g',Old_Gains(3)),...
                    sprintf('k_{a2}=%1.2g',Old_Gains(5)), sprintf('k_{v2}=%1.2g',Old_Gains(6)), sprintf('k_{d2}=%1.2g',Old_Gains(7)),'Interpreter','none',...
                    'Location', 'northeast');
                ylabel(app.UIAxes, [tag_ag ' EMG']); xlabel(app.UIAxes, 'Time (s)');

%             elseif strcmp(ReconName,"Recon_Agonist_TotalDual_Cz") || strcmp(ReconName,"Recon_Agonist_TotalDual_beta") % for SRMs w/ only 4 predictors & 2 delays
%                 plot(app.UIAxes, atime(ind_time) + Old_Gains(4),...
%                     predictors(1,:)*Old_Gains(1)+backLev,...
%                     'g-','LineWidth',1.2) % CoM Acc Component
%                 plot(app.UIAxes, atime(ind_time)+Old_Gains(4),...
%                     predictors(2,:)*Old_Gains(2)+backLev,...
%                     'g.','LineWidth',0.5) % CoM Vel Component
%                 plot(app.UIAxes, atime(ind_time)+Old_Gains(4),...
%                     predictors(3,:)*Old_Gains(3)+backLev,...
%                     'g--','LineWidth',0.5) % CoM Disp component
%                 plot(app.UIAxes, atime(ind_time)+Old_Gains(end),...
%                     predictors(4,:)*Old_Gains(5)+backLev,...
%                     'r-','LineWidth',1) % Ctx component
%                 legend('Data',ReconName,...
%                     sprintf('k_{a1}=%1.2g',Old_Gains(1)), sprintf('k_{v1}=%1.2g',Old_Gains(2)), sprintf('k_{d1}=%1.2g',Old_Gains(3)),...
%                     sprintf('k_{Ctx}=%1.2g',Old_Gains(5)),'Interpreter','none')
%                 ylabel(ReconName); xlabel('Time (s)');
            
            end
            title(app.UIAxes, "Original Reconstruction" + " (R2 = " + num2str(round(Old_fit(1),2)) + ", VAF = " + num2str(round(Old_fit(2),2)) + ")")
            xlim(xl); ylim(yl);
            hold(app.UIAxes,'on')

            app.Old_Gains_global = Old_Gains;
        end

        % Value changed function: k_a1Slider
        function k_a1SliderValueChangedhh(app, event)
            value = app.k_a1Slider.Value;
            app.New_Gains(1) = value;
        end

        % Value changed function: k_v1Slider
        function k_v1SliderValueChanged(app, event)
            value = app.k_v1Slider.Value;
            app.New_Gains(2) = value;
        end

        % Value changed function: k_d1Slider
        function k_d1SliderValueChanged(app, event)
            value = app.k_d1Slider.Value;
            app.New_Gains(3) = value;
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.New_Gains(4) = value;
        end

        % Value changed function: k_a2Slider
        function k_a2SliderValueChanged(app, event)
            value = app.k_a2Slider.Value;
            app.New_Gains(5) = value;
        end

        % Value changed function: k_v2Slider
        function k_v2SliderValueChanged(app, event)
            value = app.k_v2Slider.Value;
            app.New_Gains(6) = value;
        end

        % Value changed function: k_d2Slider
        function k_d2SliderValueChanged(app, event)
            value = app.k_d2Slider.Value;
            app.New_Gains(7) = value;
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.New_Gains(8) = value;
        end

        % Button pushed function: PlotNewReconstructionButton
        function PlotNewReconstructionButtonPushed(app, event)
            participant = app.ParticipantDropDown.Value;
            direction = app.PerturbationDirectionDropDown.Value;
            direction = str2num(direction);
            mag = app.PerturbationMagnitudeDropDown.Value;
            mag = str2num(mag);
            ReconName = app.ReconstructionTypeDropDown.Value;
            addpath('matlabUtilities-master', 'SRMUtilities');

            %% create index and define plotting limits
            ind_row = find(strcmp(app.dataAv.patient,participant) & app.dataAv.pertdir_calc_round_deg == direction & app.dataAv.condition == mag); % participant/condition index
            xl = [-0.2 1.6];
            yl = [-0.6 1.2];

            %% specify CoM kinematics and time window
            atime = app.dataAv.atime(ind_row,:); % Modify CoM acc with stiction model from Welch and Ting 2009
            a = app.dataAv.COMAccel_Y(ind_row,:);
            a = stictionTemplate('a',a,'t',atime);
            v = app.dataAv.COMVelo_Y(ind_row,:);
            d = app.dataAv.COMPosminusLVDT_Y(ind_row,:);
            % remove basline from CoM kinematics - not needed for "a" due to stiction model
            d = d-mean(d(app.dataAv.atime(1,:)<-0.1));
            v = v-mean(v(app.dataAv.atime(1,:)<-0.1));
            % specify time fit by SRM - FROM HOA_PD_SRM.m
            max_time = 1.2;
            min_time = -0.2;
            ind_time = find(app.dataAv.atime(1,:) > min_time & app.dataAv.atime(1,:) <= max_time); % adjust window that will be fit by the SRM
            
            %% flip the CoM kinematic signal depending on pert direction and specify ag/antag EMG
            if direction == 90 %forward pert
                a_ag = -a; v_ag = -v; d_ag = -d;
                a_antag = a; v_antag = v; d_antag = d;
                agonist = app.dataAv.EMG_TA_L_norm(ind_row,:); tag_ag = 'TA'; %ag_norm = dataAv.EMG_TA_L_norm(ind_row,:);
                antagonist = app.dataAv.EMG_MGAS_L_norm(ind_row,:); tag_antag = 'MG'; antag_norm = app.dataAv.EMG_MGAS_L_norm(ind_row,:);
            elseif direction == 270 %backward pert
                a_ag = a; v_ag = v; d_ag = d;
                a_antag = -a; v_antag = -v; d_antag = -d;
                antagonist = app.dataAv.EMG_TA_L_norm(ind_row,:); tag_antag = 'TA'; antag_norm = app.dataAv.EMG_TA_L_norm(ind_row,:);
                agonist = app.dataAv.EMG_MGAS_L_norm(ind_row,:); tag_ag = 'MG'; %ag_norm = dataAv.EMG_MGAS_L_norm(ind_row,:);
            end

            %% specify EEG data
            Cz = app.dataAv.Cz_norm(ind_row,:);
            beta = app.dataAv.beta_ersp_norm(ind_row,:);
            
            %% Specify appropriate neurophysiological data, SRM recon, and gains
            cla(app.UIAxes2)   
            clf(app.UIAxes2)  
            
            if strcmp(ReconName,"Recon_Agonist")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
                measuredData = agonist(ind_time); % what the SRM is reconstructing
%                 Old_Recon = app.dataAv.Recon_Agonist(ind_row,:); %SRM reconstruction time series
%                 Old_Gains = app.dataAv.Gains_Ag(ind_row,:); %SRM reconstruction gains
%                 Old_fit = app.dataAv.fit_agonist(ind_row,:); %SRM reconstruction accuracy
            
                app.New_Recon = assembleChannel(predictors,app.New_Gains(1:3),app.New_Gains(end),atime(ind_time));
            
            elseif strcmp(ReconName,"Recon_Agonist_TotalDual_CoM")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time);...
                    a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
                measuredData = agonist(ind_time); % what the SRM is reconstructing
%                 Old_Recon = app.dataAv.Recon_Agonist_TotalDual_CoM(ind_row,:); %SRM reconstruction time series
%                 Old_Gains = app.dataAv.Gains_Ag_TotalDual_CoM(ind_row,:); %SRM reconstruction gains
%                 Old_fit = app.dataAv.fit_agonist_TotalDual_CoM(ind_row,:); %SRM reconstruction accuracy
            
                app.New_Recon = assembleTwoChannels(predictors(1:3,:),app.New_Gains(1:3),app.New_Gains(4),...
                    predictors(4:6,:),app.New_Gains(5:7),app.New_Gains(end),atime(ind_time));
            
%             elseif strcmp(ReconName,"Recon_Agonist_TotalDual_Cz")
%                 predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time); -Cz(ind_time)]; % SRM predictors
%                 measuredData = agonist(ind_time); % what the SRM is reconstructing
%                 Old_Recon = app.dataAv.Recon_Agonist_TotalDual_Cz(ind_row,:); %SRM reconstruction time series
%                 Old_Gains = app.dataAv.Gains_Ag_TotalDual_Cz(ind_row,:); %SRM reconstruction gains
%                 Old_fit = app.dataAv.fit_agonist_TotalDual_Cz(ind_row,:); %SRM reconstruction accuracy
%             
%                 New_Recon = assembleTwoChannels(predictors(1:3,:),New_Gains(1:3),New_Gains(4),...
%                     predictors(4,:),New_Gains(5),New_Gains(end),atime(ind_time));
%             
%             elseif strcmp(ReconName,"Recon_Agonist_TotalDual_beta")
%                 predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time); beta(ind_time)]; % SRM predictors
%                 measuredData = agonist(ind_time); % what the SRM is reconstructing
%                 Old_Recon = app.dataAv.Recon_Agonist_TotalDual_beta(ind_row,:); %SRM reconstruction time series
%                 Old_Gains = app.dataAv.Gains_Ag_TotalDual_beta(ind_row,:); %SRM reconstruction gains
%                 Old_fit = app.dataAv.fit_agonist_TotalDual_beta(ind_row,:); %SRM reconstruction accuracy
%             
%                 New_Recon = assembleTwoChannels(predictors(1:3,:),New_Gains(1:3),New_Gains(4),...
%                     predictors(4,:),New_Gains(5),New_Gains(end),atime(ind_time));
%             
            elseif strcmp(ReconName,"Recon_Cz")
                predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
                measuredData = -Cz(ind_time); % what the SRM is reconstructing
%                 Old_Recon = -app.dataAv.Recon_Cz(ind_row,:); %SRM reconstruction time series
%                 Old_Gains = app.dataAv.Gains_Cz(ind_row,:); %SRM reconstruction gains
%                 Old_fit = app.dataAv.fit_Cz(ind_row,:); %SRM reconstruction accuracy
            
                app.New_Recon = assembleChannel(predictors,app.New_Gains(1:3),app.New_Gains(end),atime(ind_time));
              
%             elseif strcmp(ReconName,"Recon_beta")
%                 predictors = [a_ag(ind_time); v_ag(ind_time); d_ag(ind_time)]; % SRM predictors
%                 measuredData = beta(ind_time); % what the SRM is reconstructing
%                 Old_Recon = app.dataAv.Recon_Beta(ind_row,:); %SRM reconstruction time series
%                 Old_Gains = app.dataAv.Gains_Beta(ind_row,:); %SRM reconstruction gains
%                 Old_fit = app.dataAv.fit_beta(ind_row,:); %SRM reconstruction accuracy
%             
%                 New_Recon = assembleChannel(predictors,New_Gains(1:3),New_Gains(end),atime(ind_time));
%             
%             elseif strcmp(ReconName,"Recon_Antagonist")
%                 predictors = [a_antag(ind_time); v_antag(ind_time); d_antag(ind_time);....
%                     -a_antag(ind_time); -v_antag(ind_time); -d_antag(ind_time)]; % SRM predictors
%                 measuredData = antagonist(ind_time); % what the SRM is reconstructing
%                 Old_Recon = app.dataAv.Recon_Antagonist(ind_row,:); %SRM reconstruction time series
%                 Old_Gains = app.dataAv.Gains_Antag(ind_row,:); %SRM reconstruction gains
%                 Old_fit = app.dataAv.fit_antagonist(ind_row,:); %SRM reconstruction accuracy
%             
%                 New_Recon = assembleTwoChannels(predictors(1:3,:),New_Gains(1:3),New_Gains(4),...
%                     predictors(4:6,:),New_Gains(5:7),New_Gains(end),atime(ind_time));
            
            else
                error('Specified SRMType is not in dataAv')
            end
            
            %% Calculate R2 and VAF for new fit
            app.New_fit = [NaN NaN];
            app.New_fit(1) = rsqr(measuredData',app.New_Recon');
            app.New_fit(2) = rsqr_uncentered(measuredData',app.New_Recon');
%             output = table(participant, direction, mag, ReconName, app.New_Gains, New_fit, New_Recon, Old_Gains, Old_Recon);
            
            %% plot New SRM Recons
            backLev = mean(measuredData(atime(ind_time) < -0.1),'omitnan');
%             title_string = {participant + " Mag: " + string(mag) + " direc: " + string(direction) + " " + ReconName};
            
            figure('visible','off'); 
            set(gcf,'WindowStyle','docked')
            colors = [
                0.8500 0.3250 0.0980; % Orange
                0.9290 0.6940 0.1250; % Yellow
                0.4940 0.1840 0.5560; % Purple
                0.4660 0.6740 0.1880; % Green
                0.3010 0.7450 0.9330; % Light Blue
                1 0.5 0.6;            % Pink
            ];

            %% New Recon
            plotij(2,1,1,1); hold(app.UIAxes2,'on')
            plot(app.UIAxes2, atime(ind_time),measuredData,'Color', colors(5,:),'LineWidth',2) % raw data
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2, atime(ind_time),app.New_Recon+backLev,'Color', colors(6,:),'LineWidth',2) % Reconstruction
            hold(app.UIAxes2,'on')

            if strcmp(ReconName,"Recon_Agonist") || strcmp(ReconName,"Recon_beta") || strcmp(ReconName,"Recon_Cz") % for SRMs w/ only 3 predictors & 1 delay
                plot(app.UIAxes2, atime(ind_time) + app.New_Gains(4),...
                    predictors(1,:)*app.New_Gains(1)+backLev,...
                    'Color', colors(4,:),'Linestyle', '-','LineWidth',1.2) % CoM Acc Component
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2, atime(ind_time)+app.New_Gains(4),...
                    predictors(2,:)*app.New_Gains(2)+backLev,...
                    'Color', colors(4,:),'Linestyle', ':','LineWidth',0.5) % CoM Vel Component
                hold(app.UIAxes2,'on')
                plot(app.UIAxes2, atime(ind_time)+app.New_Gains(4),...
                    predictors(3,:)*app.New_Gains(3)+backLev,...
                    'Color', colors(4,:),'Linestyle', '--','LineWidth',0.5) % CoM Disp component
                hold(app.UIAxes2,'on')
                h = area(app.UIAxes2, atime(ind_time),app.New_Recon+backLev, 'EdgeColor', colors(6,:));
                h.FaceColor = [1 0.5 0.6]; % Set the shading color (RGB triplet)
                h.FaceAlpha = 0.1; % Set the transparency (value between 0 and 1)
                legend(app.UIAxes2,'Data',ReconName,...
                    sprintf('k_{a1}=%1.2g',app.New_Gains(1)), sprintf('k_{v1}=%1.2g',app.New_Gains(2)), sprintf('k_{d1}=%1.2g',app.New_Gains(3)),'Interpreter','none');
                if strcmp(ReconName,"Recon_Cz")
                    ylabel(app.UIAxes2, 'Cz'); xlabel(app.UIAxes, 'Time (s)');
                elseif strcmp(ReconName,"Recon_Agonist")
                    ylabel(app.UIAxes2, [tag_ag ' EMG']); xlabel(app.UIAxes, 'Time (s)');
                end
               

            elseif strcmp(ReconName,"Recon_Agonist_TotalDual_CoM") || strcmp(ReconName,"Recon_Antagonist") % for SRMs w/ 6 predictors & 2 delays
                plot(app.UIAxes2,atime(ind_time) + app.New_Gains(4),...
                    predictors(1,:)*app.New_Gains(1)+backLev,...
                    'Color', colors(4,:), 'Linestyle', '-','LineWidth',1.2) % CoM Acc 1 Component
                plot(app.UIAxes2,atime(ind_time)+app.New_Gains(4),...
                    predictors(2,:)*app.New_Gains(2)+backLev,...
                    'Color', colors(4,:), 'Linestyle', ':','LineWidth',0.5) % CoM Vel 1 Component
                plot(app.UIAxes2,atime(ind_time)+app.New_Gains(4),...
                    predictors(3,:)*app.New_Gains(3)+backLev,...
                    'Color', colors(4,:), 'Linestyle', '--','LineWidth',0.5) % CoM Disp 1 component
                plot(app.UIAxes2,atime(ind_time)+app.New_Gains(end),...
                    predictors(4,:)*app.New_Gains(5)+backLev,...
                    'Color', colors(2,:), 'Linestyle', '-','LineWidth',1.2) % CoM Acc 2 component 
                plot(app.UIAxes2,atime(ind_time)+app.New_Gains(end),...
                    predictors(5,:)*app.New_Gains(6)+backLev,...
                    'Color', colors(2,:), 'Linestyle', ':','LineWidth',0.5) % CoM Vel 2 component
                plot(app.UIAxes2,atime(ind_time)+app.New_Gains(end),...
                    predictors(6,:)*app.New_Gains(7)+backLev,...
                    'Color', colors(2,:), 'Linestyle', '--','LineWidth',0.5)% CoM Disp 2 component
                h = area(app.UIAxes2,atime(ind_time),app.New_Recon+backLev, 'EdgeColor', colors(6,:));
                h.FaceColor = [1 0.5 0.6]; % Set the shading color (RGB triplet)
                h.FaceAlpha = 0.1; % Set the transparency (value between 0 and 1)

                legend(app.UIAxes2,'Data',ReconName,...
                    sprintf('k_{a1}=%1.2g',app.New_Gains(1)), sprintf('k_{v1}=%1.2g',app.New_Gains(2)), sprintf('k_{d1}=%1.2g',app.New_Gains(3)),...
                    sprintf('k_{a2}=%1.2g',app.New_Gains(5)), sprintf('k_{v2}=%1.2g',app.New_Gains(6)), sprintf('k_{d2}=%1.2g',app.New_Gains(7)),'Interpreter','none');
                ylabel(app.UIAxes2, [tag_ag ' EMG']); xlabel(app.UIAxes2, 'Time (s)');

%             elseif strcmp(ReconName,"Recon_Agonist_TotalDual_Cz") || strcmp(ReconName,"Recon_Agonist_TotalDual_beta") % for SRMs w/ only 4 predictors & 2 delays
%                 plot(atime(ind_time) + New_Gains(4),...
%                     predictors(1,:)*New_Gains(1)+backLev,...
%                     'g-','LineWidth',1) % CoM Acc Component
%                 plot(atime(ind_time)+New_Gains(4),...
%                     predictors(2,:)*New_Gains(2)+backLev,...
%                     'g.','LineWidth',1) % CoM Vel Component
%                 plot(atime(ind_time)+New_Gains(4),...
%                     predictors(3,:)*New_Gains(3)+backLev,...
%                     'g--','LineWidth',1) % CoM Disp component
%                 plot(atime(ind_time)+New_Gains(end),...
%                     predictors(4,:)*New_Gains(5)+backLev,...
%                     'r-','LineWidth',1) % Cz component
%                 legend('Data',ReconName,...
%                     sprintf('k_{a1}=%1.2g',New_Gains(1)), sprintf('k_{v1}=%1.2g',New_Gains(2)), sprintf('k_{d1}=%1.2g',New_Gains(3)),...
%                     sprintf('k_{Ctx}=%1.2g',New_Gains(5)))
%                 ylabel(ReconName); xlabel('Time (s)');

            end
            xlim(xl); ylim(yl);
            title(app.UIAxes2, "New Reconstruction" + " (R2 = " + num2str(round(app.New_fit(1),2)) + ", VAF = " + num2str(round(app.New_fit(2),2)) + ")");
                        
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            participant_str = app.ParticipantDropDown.Value;
            participant = cellstr(participant_str);
            direction = app.PerturbationDirectionDropDown.Value;
            direction = str2num(direction);
            mag = app.PerturbationMagnitudeDropDown.Value;
            mag = str2num(mag);
            ReconName_str = app.ReconstructionTypeDropDown.Value;
            ReconName = cellstr(ReconName_str);
            

            output = table(participant, direction, mag, ReconName, app.New_Gains, app.New_fit, app.New_Recon, app.Old_Gains_global, app.Old_Recon);
            if mag == 7.5
                mag = 75;
            end
            fn = sprintf('%s_%d_%d_%s', participant_str, direction, mag, ReconName_str);
            save(fn, 'output')

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Old Reconstruction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'New Reconstruction')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.k_a1SliderLabel = uilabel(app.UIFigure);
            app.k_a1SliderLabel.Text = 'k_a1';
            app.k_a1Slider = uislider(app.UIFigure);
            app.k_a1Slider.ValueChangedFcn = createCallbackFcn(app, @k_a1SliderValueChangedhh, true);
            app.k_v1SliderLabel = uilabel(app.UIFigure);
            app.k_v1SliderLabel.Text = 'k_v1';
            app.k_v1Slider = uislider(app.UIFigure);
            app.k_v1Slider.ValueChangedFcn = createCallbackFcn(app, @k_v1SliderValueChanged, true);
            app.k_d1SliderLabel = uilabel(app.UIFigure);
            app.k_d1SliderLabel.Text = 'k_d1';
            app.k_d1Slider = uislider(app.UIFigure);
            app.k_d1Slider.ValueChangedFcn = createCallbackFcn(app, @k_d1SliderValueChanged, true);
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = ' λ_1';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.k_a2SliderLabel = uilabel(app.UIFigure);
            app.k_a2SliderLabel.Text = 'k_a2';
            app.k_a2Slider = uislider(app.UIFigure);
            app.k_a2Slider.ValueChangedFcn = createCallbackFcn(app, @k_a2SliderValueChanged, true);
            app.k_v2SliderLabel = uilabel(app.UIFigure);
            app.k_v2SliderLabel.Text = 'k_v2';
            app.k_v2Slider = uislider(app.UIFigure);
            app.k_v2Slider.ValueChangedFcn = createCallbackFcn(app, @k_v2SliderValueChanged, true);
            app.k_d2SliderLabel = uilabel(app.UIFigure);
            app.k_d2SliderLabel.Text = 'k_d2';
            app.k_d2Slider = uislider(app.UIFigure);
            app.k_d2Slider.ValueChangedFcn = createCallbackFcn(app, @k_d2SliderValueChanged, true);
            app.Slider_2Label = uilabel(app.UIFigure);
            app.Slider_2Label.Text = ' λ_2';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.ParticipantDropDownLabel = uilabel(app.UIFigure);
            app.ParticipantDropDownLabel.Text = 'Participant';
            app.ParticipantDropDown = uidropdown(app.UIFigure);
            app.ParticipantDropDown.DropDownOpeningFcn = createCallbackFcn(app, @choose_participant, true);
            app.ReconstructionTypeDropDownLabel = uilabel(app.UIFigure);
            app.ReconstructionTypeDropDownLabel.Text = 'Reconstruction Type';
            app.ReconstructionTypeDropDown = uidropdown(app.UIFigure);
            app.ReconstructionTypeDropDown.Items = {'Recon_Agonist', 'Recon_Cz', 'Recon_Agonist_TotalDual_CoM', 'Recon_Antagonist'};
            app.ReconstructionTypeDropDown.Value = 'Recon_Agonist';
            app.PerturbationDirectionDropDownLabel = uilabel(app.UIFigure);
            app.PerturbationDirectionDropDownLabel.Text = 'Perturbation Direction';
            app.PerturbationDirectionDropDown = uidropdown(app.UIFigure);
            app.PerturbationDirectionDropDown.DropDownOpeningFcn = createCallbackFcn(app, @choose_direction, true);
            app.PerturbationMagnitudeDropDownLabel = uilabel(app.UIFigure);
            app.PerturbationMagnitudeDropDownLabel.Text = 'Perturbation Magnitude';
            app.PerturbationMagnitudeDropDown = uidropdown(app.UIFigure);
            app.PerturbationMagnitudeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @choose_magnitude, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadData, true);
            app.LoadButton.Text = 'Load';
            app.PlotOriginalReconstructionButton = uibutton(app.UIFigure, 'push');
            app.PlotOriginalReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @plot_originalRecon, true);
            app.PlotOriginalReconstructionButton.Text = 'Plot Original Reconstruction';
            app.PlotNewReconstructionButton = uibutton(app.UIFigure, 'push');
            app.PlotNewReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotNewReconstructionButtonPushed, true);
            app.PlotNewReconstructionButton.Text = {'Plot New '; 'Reconstruction'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HOA_PD_SRM_HandFit_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end